classdef ProcessGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PMVAltpapierKlassifiziererUIFigure  matlab.ui.Figure
        OptionenMenu                   matlab.ui.container.Menu
        ProzessEinstellungenladenMenu  matlab.ui.container.Menu
        ProzessEinstellungenspeichernMenu  matlab.ui.container.Menu
        GUIEinstellungenMenu           matlab.ui.container.Menu
        HilfeMenu_2                    matlab.ui.container.Menu
        HilfeMenu_3                    matlab.ui.container.Menu
        aboutMenu                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        GesamtprozessTab               matlab.ui.container.Tab
        IsolierungPanel                matlab.ui.container.Panel
        TrommelLampLabel               matlab.ui.control.Label
        DrumFeeder_Lamp                matlab.ui.control.Lamp
        ConvBelt_EditField             matlab.ui.control.EditField
        FrderbandLamp_3Label           matlab.ui.control.Label
        ConvBelt_Lamp                  matlab.ui.control.Lamp
        Robot_EditField                matlab.ui.control.EditField
        RoboterLampLabel               matlab.ui.control.Label
        Robot_Lamp                     matlab.ui.control.Lamp
        DrumFeeder_EditField           matlab.ui.control.EditField
        Gripper_EditField              matlab.ui.control.EditField
        GreiferLampLabel               matlab.ui.control.Label
        Gripper_Lamp                   matlab.ui.control.Lamp
        ObjectDetection_EditField      matlab.ui.control.EditField
        ObjekterkennungLamp_2Label     matlab.ui.control.Label
        ObjectDetection_Lamp           matlab.ui.control.Lamp
        MesssystemPanel                matlab.ui.container.Panel
        WeighingBelt_EditField         matlab.ui.control.EditField
        FrderbandLamp_4Label           matlab.ui.control.Label
        WeighingBelt_Lamp              matlab.ui.control.Lamp
        Camera_EditField               matlab.ui.control.EditField
        Scale_EditField                matlab.ui.control.EditField
        KameraLampLabel                matlab.ui.control.Label
        Camera_Lamp                    matlab.ui.control.Lamp
        WaageLampLabel                 matlab.ui.control.Label
        Scale_Lamp                     matlab.ui.control.Lamp
        Light_EditField                matlab.ui.control.EditField
        LichtLabel                     matlab.ui.control.Label
        Light_Lamp                     matlab.ui.control.Lamp
        MeasuringSystem_EditField      matlab.ui.control.EditField
        MesssystemLabel                matlab.ui.control.Label
        MeasuringSystem_Lamp           matlab.ui.control.Lamp
        IsolationDevice_EditField      matlab.ui.control.EditField
        IsolierungLabel                matlab.ui.control.Label
        IsolationDevice_Lamp           matlab.ui.control.Lamp
        KommunikationPanel             matlab.ui.container.Panel
        Can_EditField                  matlab.ui.control.EditField
        CANBusLabel                    matlab.ui.control.Label
        Can_Lamp                       matlab.ui.control.Lamp
        MesssystemTab                  matlab.ui.container.Tab
        KameraPanel                    matlab.ui.container.Panel
        RotLampLabel                   matlab.ui.control.Label
        RotLamp                        matlab.ui.control.Lamp
        GrnLampLabel                   matlab.ui.control.Label
        GrnLamp                        matlab.ui.control.Lamp
        BlauLampLabel                  matlab.ui.control.Label
        BlauLamp                       matlab.ui.control.Lamp
        WeiLampLabel                   matlab.ui.control.Label
        WeiLamp                        matlab.ui.control.Lamp
        LichtBitcodeEditFieldLabel     matlab.ui.control.Label
        LichtBitcodeEditField          matlab.ui.control.EditField
        BindingfrEditfieldEditfieldChangedLampenLabel  matlab.ui.control.Label
        UVLampLabel                    matlab.ui.control.Label
        UVLamp                         matlab.ui.control.Lamp
        IsolationDevice_Tab            matlab.ui.container.Tab
        IsolationDevice_TabGroup       matlab.ui.container.TabGroup
        RoboterGreiferTab              matlab.ui.container.Tab
        ZustandEditFieldLabel          matlab.ui.control.Label
        ZustandEditField               matlab.ui.control.EditField
        PosePanel                      matlab.ui.container.Panel
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        ryEditFieldLabel               matlab.ui.control.Label
        ryEditField                    matlab.ui.control.NumericEditField
        rzEditFieldLabel               matlab.ui.control.Label
        rzEditField                    matlab.ui.control.NumericEditField
        rxEditFieldLabel               matlab.ui.control.Label
        rxEditField                    matlab.ui.control.NumericEditField
        SteuerungCheckBox              matlab.ui.control.CheckBox
        BewegenButton                  matlab.ui.control.Button
        SollLabel                      matlab.ui.control.Label
        IstLabel                       matlab.ui.control.Label
        xEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        zEditField_2Label              matlab.ui.control.Label
        zEditField_2                   matlab.ui.control.NumericEditField
        ryEditField_2Label             matlab.ui.control.Label
        ryEditField_2                  matlab.ui.control.NumericEditField
        rzEditField_2Label             matlab.ui.control.Label
        rzEditField_2                  matlab.ui.control.NumericEditField
        rxEditField_2Label             matlab.ui.control.Label
        rxEditField_2                  matlab.ui.control.NumericEditField
        GreiferPanel                   matlab.ui.container.Panel
        ZangeLinksGaugeLabel           matlab.ui.control.Label
        ZangeLinksGauge                matlab.ui.control.NinetyDegreeGauge
        ZangeRechtsGaugeLabel          matlab.ui.control.Label
        ZangeRechtsGauge               matlab.ui.control.NinetyDegreeGauge
        KontaktLampLabel               matlab.ui.control.Label
        KontaktLamp                    matlab.ui.control.Lamp
        OffsetEditFieldLabel           matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        ZangeRechts_EditField          matlab.ui.control.NumericEditField
        ZangeLinks_EditField           matlab.ui.control.NumericEditField
        OpenCloseGripper_Button        matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        UnterdruckpumpePanel           matlab.ui.container.Panel
        SignalLampLabel                matlab.ui.control.Label
        VacuumSignalLamp               matlab.ui.control.Lamp
        DruckGaugeLabel                matlab.ui.control.Label
        DruckGauge                     matlab.ui.control.LinearGauge
        AnAusButton                    matlab.ui.control.Button
        FrderbandPanel                 matlab.ui.container.Panel
        LichtschrankeLampLabel         matlab.ui.control.Label
        IsoLichtschrankeLamp           matlab.ui.control.Lamp
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        AnAusButton_2                  matlab.ui.control.StateButton
        ObjekterkennungTab             matlab.ui.container.Tab
        Vorschau_Button                matlab.ui.control.StateButton
        BereichObjekterkennungPanel    matlab.ui.container.Panel
        XSpinnerLabel                  matlab.ui.control.Label
        ROI_X_Spinner                  matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        ROI_Y_Spinner                  matlab.ui.control.Spinner
        HheSpinnerLabel                matlab.ui.control.Label
        ROI_Hohe_Spinner               matlab.ui.control.Spinner
        BreiteSpinnerLabel             matlab.ui.control.Label
        ROI_Breite_Spinner             matlab.ui.control.Spinner
        ROIShow_Button                 matlab.ui.control.StateButton
        ObjDetectCam_Axes              matlab.ui.control.UIAxes
        BereichGreifenPanel            matlab.ui.container.Panel
        XSpinnerLabel_2                matlab.ui.control.Label
        ROA_X_Spinner                  matlab.ui.control.Spinner
        YSpinnerLabel_2                matlab.ui.control.Label
        ROA_Y_Spinner                  matlab.ui.control.Spinner
        HheSpinnerLabel_2              matlab.ui.control.Label
        ROA_Hohe_Spinner               matlab.ui.control.Spinner
        BreiteSpinnerLabel_2           matlab.ui.control.Label
        ROA_Breite_Spinner             matlab.ui.control.Spinner
        ROAShow_Button                 matlab.ui.control.StateButton
        FrderbandTab                   matlab.ui.container.Tab
        TrommelTab                     matlab.ui.container.Tab
        LoggingPanel                   matlab.ui.container.Panel
        FehlerCheckBox                 matlab.ui.control.CheckBox
        WarnungCheckBox                matlab.ui.control.CheckBox
        InfoCheckBox                   matlab.ui.control.CheckBox
        DebugCheckBox                  matlab.ui.control.CheckBox
        Log_UITable                    matlab.ui.control.Table
        angezeigteLogsSpinnerLabel     matlab.ui.control.Label
        AngezeigteLogs_Spinner         matlab.ui.control.Spinner
        FilterEditFieldLabel           matlab.ui.control.Label
        LogFilter_EditField            matlab.ui.control.EditField
        SteuerungPanel                 matlab.ui.container.Panel
        Start_Button                   matlab.ui.control.Button
        Stop_Button                    matlab.ui.control.Button
        ProbenLabel                    matlab.ui.control.Label
        Samples_Spinner                matlab.ui.control.Spinner
        ContinuousOperation_CheckBox   matlab.ui.control.CheckBox
        Process_EditField              matlab.ui.control.EditField
        ProzessLabel                   matlab.ui.control.Label
        Process_Lamp                   matlab.ui.control.Lamp
        Stop_Button_2                  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        RemainingSamplesSlash_Label    matlab.ui.control.Label
        RemainingSamples_EditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        logger % Description
        allowed_log_levels = [1,1,1,1]; % Description
        binding_managers % Description
    end
    
    properties (Access = private)
        process % Description
        process_init_complete = false; % Description
        ObjDetectCam_Roi_Rectangle % Description
        ObjDetectCam_Roa_Rectangle % Description
    end

    methods (Access = public)
    
        function updateStates(app, ~, ~)
%             try
        % MAJOR STATES
%                 app.updateState(app.Process_Lamp,app.Process_EditField, app.process);
%                 % ISOLATION DEVICE
%                 app.updateState(app.IsolationDevice_Lamp,app.IsolationDevice_EditField, app.process.isoDevice);
%                 app.updateState(app.Robot_Lamp,app.Robot_EditField, app.process.isoDevice.robot);
%                 app.updateState(app.ObjectDetection_Lamp,app.ObjectDetection_EditField, app.process.isoDevice.robot.objDetection);
%                 app.updateState(app.Gripper_Lamp,app.Gripper_EditField, app.process.isoDevice.robot.gripper);
%                 app.updateState(app.DrumFeeder_Lamp,app.DrumFeeder_EditField, app.process.isoDevice.drumFeeder);
%                 app.updateState(app.ConvBelt_Lamp,app.ConvBelt_EditField, app.process.isoDevice.convBelt);
%                 %MEASURING SYSTEM
%                 app.updateState(app.MeasuringSystem_Lamp,app.MeasuringSystem_EditField, app.process.measSystem);
%                 app.updateState(app.WeighingBelt_Lamp,app.WeighingBelt_EditField, app.process.measSystem.weighingBelt);
%                 app.updateState(app.Camera_Lamp,app.Camera_EditField, app.process.measSystem.cam);
%                 app.updateState(app.Light_Lamp,app.Light_EditField, app.process.measSystem.light);
%                 app.updateState(app.Scale_Lamp,app.Scale_EditField, app.process.measSystem.scale);
%             disp('update')
            for i = 1:length(app.gui_bindings)
%                  disp(i)
                app.gui_bindings(i).eval()
            end
                
            % MINOR STATES
%                 app.updateConveyorBeltState_minor();
%                 app.updateRobotState_minor();
%                 app.updateGripperState_minor();
%             catch ME
%                 disp('timerfcn error')
% %                 disp(ME.message)
%             end
        end
        
        function updateState(app, lamp, editfield, state_object)
            state_object.updateState();
            state = state_object.getState();
            state_description = state_object.getStateDescription();
            lamp.Color = app.lamp_colors.(state_object.STATE_STRINGS{state});
            editfield.Value = state_description;
        end
        
        function logToTable(app, log_entry)
        % Fügt log_entry an das Ende (Oben) der Tabelle ein, wenn die
        % Kriterien erfüllt sind.
            if app.isAllowedLog(log_entry)
                max_logs = app.AngezeigteLogs_Spinner.Value;
                current_logs = length(app.Log_UITable.Data);
                % Falls maximale Anzahl an Logs erreicht ist, lösche den
                % letzten Eintrag
                if current_logs >= max_logs
                    app.Log_UITable.Data = app.Log_UITable.Data(1:end-1,:);
                end
                app.addLogToTable(log_entry);
            end
        end
        
        function addLogToTable(app, log_entry)
        % Fügt log_entry an das Ende (Oben) der Tabelle ein
            app.Log_UITable.Data = [
                        {app.logger.formatTime(log_entry),...
                        app.logger.formatLevel(log_entry),...
                        app.logger.formatSource(log_entry),...
                        app.logger.formatMessage(log_entry)};...
                        app.Log_UITable.Data];        
        end
        
        function is_allowed = isAllowedLog(app, log_entry)
        % Überprüft ob log_entry die Kriterien (Level & Filter) erfüllt
            is_allowed = false;
            if contains(lower(app.logger.formatSource(log_entry)), lower(app.LogFilter_EditField.Value))
                if app.allowed_log_levels(log_entry.level)
                    is_allowed = true;
                end
            end
        end
    
        function refreshLogTable(app)
            app.Log_UITable.Data = [];
            max_logs = app.AngezeigteLogs_Spinner.Value;
            current_logs = 0;
            log_ids = [];
            % Liste mit Logs erstellen, welche die Kriterien erfüllen
            for i = length(app.logger.log_store):-1:1
                if app.isAllowedLog(app.logger.log_store(i))
                    log_ids = [log_ids,i];
                    current_logs = current_logs + 1;
                    if current_logs == max_logs
                        break;
                    end
                end
            end
            % Gültige Logs in Tabelle hinzufügen
            for ii = length(log_ids):-1:1
                app.addLogToTable(app.logger.log_store(log_ids(ii)));
            end
        end
        
        function test(app)
            app.Start_ButtonPushed([])
            app.ContinuousOperation_CheckBox.Value = 1;
            app.Samples_Spinner.Value = 1;
            app.Start_ButtonPushed([])
        end
    end
    
    methods (Access = private)
        function initObjDetectCam_Axes(app)
            cam = app.process.isoDevice.robot.objDetection.cam;
            frame = snapshot(cam); 
            im = image(app.ObjDetectCam_Axes,zeros(size(frame),'uint8')); 
            axis(app.ObjDetectCam_Axes,'image');
            preview(cam,im)
            app.Vorschau_Button.Enable = false;
        end
        
        function refreshObjDetectCam_Axes(app)
            app.ROI_X_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(2);
            app.ROI_Y_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(1);
            app.ROI_Hohe_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(3);
            app.ROI_Breite_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(4);
        
            roi_active = app.ROIShow_Button.Value;
            if roi_active
                delete(app.ObjDetectCam_Roi_Rectangle)
                roi = app.process.isoDevice.robot.objDetection.roi;
                app.ObjDetectCam_Roi_Rectangle = rectangle(app.ObjDetectCam_Axes,'Position',[roi(2),roi(1),roi(4),roi(3)],'EdgeColor','r');
            end
            
            roa_active = app.ROAShow_Button.Value;
            if roa_active
                delete(app.ObjDetectCam_Roa_Rectangle)
                roa = app.process.isoDevice.robot.roa;
                app.ObjDetectCam_Roa_Rectangle = rectangle(app.ObjDetectCam_Axes,'Position',[roa(2),roa(1),roa(4),roa(3)],'EdgeColor','g');
            end
        end
        
        function initObjekterkennungTab(app)
            app.ROI_X_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(2);
            app.ROI_Y_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(1);
            app.ROI_Hohe_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(3);
            app.ROI_Breite_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(4);
        end
        
        function initGUI(app)
            app.initObjekterkennungTab();
            app.IsolationDevice_TabGroup.Visible = true;
        end
        
        function loadSettings(app)
            try
                settings = load('Back-end\settings.mat');
            catch
                settings = load('settings.mat');
            end
            app.process.isoDevice.robot.roa = settings.process.isoDevice.robot.roa;
            app.process.isoDevice.robot.objDetection.roi = settings.process.isoDevice.robot.objDetection.roi;
            app.logger.debug('Einstellungen geladen'); 
        end
        
        function saveSettings(app)
            % process nicht mit app.process verwechseln!
            process.isoDevice.robot.roa = app.process.isoDevice.robot.roa;
            process.isoDevice.robot.objDetection.roi = app.process.isoDevice.robot.objDetection.roi;
            if exist('Back-end','dir')
                save('Back-end\settings.mat',process)
            else
                save('settings.mat',process)
            end
            this.logger.debug('Einstellungen gespeichert'); 
        end
        
        function updateConveyorBeltState_minor(app)
        if app.process.isoDevice.convBelt.getState ~= app.process.OFFLINE
            light_barrier_active = app.process.isoDevice.convBelt.lightBarrierActivated();
            if light_barrier_active
                app.IsoLichtschrankeLamp.Color = [0,1,0];
            else
                app.IsoLichtschrankeLamp.Color = [0.1,0.1,0.1];
            end
        end
        end
        
        function updateRobotState_minor(app)
        if app.process.isoDevice.robot.getState ~= app.process.OFFLINE
            pressure_on = app.process.isoDevice.robot.checkPressureSensor();
            if pressure_on
                app.VacuumSignalLamp.Color = [0,1,0];
            else
                app.VacuumSignalLamp.Color = [1,0,0];
            end
        end
        end
        
        function updateGripperState_minor(app)
        if app.process.isoDevice.robot.gripper.getState ~= app.process.OFFLINE
            [left_angle, right_angle] = app.process.isoDevice.robot.gripper.readAngles();
            app.ZangeLinksGauge.Value = left_angle;
            app.ZangeRechtsGauge.Value = right_angle;
            app.ZangeLinks_EditField.Value = left_angle;
            app.ZangeRechts_EditField.Value = right_angle;
            has_contact = app.process.isoDevice.robot.gripper.checkContact();
            if has_contact
                app.KontaktLamp.Color = [0,1,0];
            else
                app.KontaktLamp.Color = [0.1,0.1,0.1];
            end
        end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, process, logger)
%             app.lamp_colors.OFFLINE = [0.65,0.65,0.65];
%             app.lamp_colors.INACTIVE = [0.07,0.62,1];
%             app.lamp_colors.ACTIVE = [0,1,0];
%             app.lamp_colors.UNKNOWN = [1,1,0];
%             app.lamp_colors.STOPPED = [0,0,0];
%             app.lamp_colors.ERROR = [1,0,0];            
            
            if nargin < 2
                app.process = [];
            else
                app.process = process;
            end
            if nargin < 3
                app.logger = [];
            else
                logger.log_remote_fcn_handle = @app.logToTable;
                logger.log_remote_fcn_active = true;
                app.logger = logger;
                app.refreshLogTable();
            end
            addProcessGuiBindings(app)
            
            % Tabgroups werden erst nach initialisierung sichtbar gemacht
            app.IsolationDevice_TabGroup.Visible = false;
        end

        % Menu selected function: HilfeMenu_3
        function HilfeMenuSelected(app, event)
            HelpGUI()
        end

        % Value changed function: DebugCheckBox
        function DebugCheckBoxValueChanged(app, event)
            app.allowed_log_levels(1) = app.DebugCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: InfoCheckBox
        function InfoCheckBoxValueChanged(app, event)
            app.allowed_log_levels(2) = app.InfoCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: WarnungCheckBox
        function WarnungCheckBoxValueChanged(app, event)
            app.allowed_log_levels(3) = app.WarnungCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: FehlerCheckBox
        function FehlerCheckBoxValueChanged(app, event)
            app.allowed_log_levels(4) = app.FehlerCheckBox.Value;
            app.refreshLogTable();
        end

        % Menu selected function: GUIEinstellungenMenu
        function GUIEinstellungenMenuSelected(app, event)
            SettingsGUI(app) 
        end

        % Button pushed function: Start_Button
        function Start_ButtonPushed(app, event)
            if app.process_init_complete
                app.Stop_Button.Enable = true;
                if app.ContinuousOperation_CheckBox.Value
                    app.process.run(intmax);
                else
                    app.process.run(app.Samples_Spinner.Value);
                end
            else
                app.Start_Button.Enable = false;
                app.process.init();
                app.Start_Button.Enable = true;
                app.Start_Button.Text = 'Start';
                app.process_init_complete = true;
                
                app.initGUI();
            end
        end

        % Value changed function: ContinuousOperation_CheckBox
        function ContinuousOperation_CheckBoxValueChanged(app, event)
            app.Samples_Spinner.Enable = ~app.ContinuousOperation_CheckBox.Value;
            app.RemainingSamples_EditField.Enable = ~app.ContinuousOperation_CheckBox.Value;
        end

        % Close request function: PMVAltpapierKlassifiziererUIFigure
        function PMVAltpapierKlassifiziererUIFigureCloseRequest(app, event)
            bm_array= struct2array(app.binding_managers);
            for i = 1:length(bm_array)
                stop(bm_array(i).timer);
            end
            delete(app)
        end

        % Value changed function: Vorschau_Button
        function Vorschau_ButtonValueChanged(app, event)
            app.initObjDetectCam_Axes();
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROIShow_Button
        function ROIShow_ButtonValueChanged(app, event)
            app.refreshObjDetectCam_Axes;
        end

        % Value changed function: ROI_X_Spinner
        function ROI_X_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(2) = app.ROI_X_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Y_Spinner
        function ROI_Y_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(1) = app.ROI_Y_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Hohe_Spinner
        function ROI_Hohe_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(3) = app.ROI_Hohe_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Breite_Spinner
        function ROI_Breite_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(4) = app.ROI_Breite_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROA_X_Spinner
        function ROA_X_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(2) = app.ROA_X_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROA_Y_Spinner
        function ROA_Y_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(1) = app.ROA_Y_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROA_Hohe_Spinner
        function ROA_Hohe_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(3) = app.ROA_Hohe_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROA_Breite_Spinner
        function ROA_Breite_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(4) = app.ROA_Breite_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROAShow_Button
        function ROAShow_ButtonValueChanged(app, event)
            app.refreshObjDetectCam_Axes;
        end

        % Menu selected function: ProzessEinstellungenladenMenu
        function ProzessEinstellungenladenMenuSelected(app, event)
            app.loadSettings();
        end

        % Menu selected function: ProzessEinstellungenspeichernMenu
        function ProzessEinstellungenspeichernMenuSelected(app, event)
            app.saveSettings();
        end

        % Value changed function: AngezeigteLogs_Spinner
        function AngezeigteLogs_SpinnerValueChanged(app, event)
            app.refreshLogTable();
        end

        % Value changed function: LogFilter_EditField
        function LogFilter_EditFieldValueChanged(app, event)
            app.refreshLogTable();
        end

        % Button pushed function: Stop_Button
        function Stop_ButtonPushed(app, event)
            app.process.isoDevice.convBelt.stop();
            app.process.isoDevice.drumFeeder.stop();
            app.process.measSystem.weighingBelt.stop();
            app.process.isoDevice.robot.switchVacuum(0);  
            app.process.isoDevice.robot.disallowToAct(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PMVAltpapierKlassifiziererUIFigure = uifigure('Visible', 'off');
            app.PMVAltpapierKlassifiziererUIFigure.Name = 'PMV Altpapier Klassifizierer';
            app.PMVAltpapierKlassifiziererUIFigure.CloseRequestFcn = createCallbackFcn(app, @PMVAltpapierKlassifiziererUIFigureCloseRequest, true);
            app.OptionenMenu = uimenu(app.PMVAltpapierKlassifiziererUIFigure);
            app.OptionenMenu.Text = 'Optionen';
            app.ProzessEinstellungenladenMenu = uimenu(app.OptionenMenu);
            app.ProzessEinstellungenladenMenu.MenuSelectedFcn = createCallbackFcn(app, @ProzessEinstellungenladenMenuSelected, true);
            app.ProzessEinstellungenladenMenu.Text = 'Prozess Einstellungen laden...';
            app.ProzessEinstellungenspeichernMenu = uimenu(app.OptionenMenu);
            app.ProzessEinstellungenspeichernMenu.MenuSelectedFcn = createCallbackFcn(app, @ProzessEinstellungenspeichernMenuSelected, true);
            app.ProzessEinstellungenspeichernMenu.Text = 'Prozess Einstellungen speichern...';
            app.GUIEinstellungenMenu = uimenu(app.OptionenMenu);
            app.GUIEinstellungenMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIEinstellungenMenuSelected, true);
            app.GUIEinstellungenMenu.Text = 'GUI - Einstellungen...';
            app.HilfeMenu_2 = uimenu(app.PMVAltpapierKlassifiziererUIFigure);
            app.HilfeMenu_2.Text = 'Hilfe';
            app.HilfeMenu_3 = uimenu(app.HilfeMenu_2);
            app.HilfeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HilfeMenuSelected, true);
            app.HilfeMenu_3.Text = 'Hilfe';
            app.aboutMenu = uimenu(app.HilfeMenu_2);
            app.aboutMenu.Text = 'Über';
            app.TabGroup = uitabgroup(app.PMVAltpapierKlassifiziererUIFigure);
            app.GesamtprozessTab = uitab(app.TabGroup);
            app.GesamtprozessTab.Title = 'Gesamtprozess';
            app.IsolierungPanel = uipanel(app.GesamtprozessTab);
            app.IsolierungPanel.Title = 'Isolierung';
            app.TrommelLampLabel = uilabel(app.IsolierungPanel);
            app.TrommelLampLabel.Text = 'Trommel';
            app.DrumFeeder_Lamp = uilamp(app.IsolierungPanel);
            app.ConvBelt_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.FrderbandLamp_3Label = uilabel(app.IsolierungPanel);
            app.FrderbandLamp_3Label.Text = 'Förderband';
            app.ConvBelt_Lamp = uilamp(app.IsolierungPanel);
            app.Robot_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.RoboterLampLabel = uilabel(app.IsolierungPanel);
            app.RoboterLampLabel.Text = 'Roboter';
            app.Robot_Lamp = uilamp(app.IsolierungPanel);
            app.DrumFeeder_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.Gripper_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.GreiferLampLabel = uilabel(app.IsolierungPanel);
            app.GreiferLampLabel.Text = 'Greifer';
            app.Gripper_Lamp = uilamp(app.IsolierungPanel);
            app.ObjectDetection_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.ObjekterkennungLamp_2Label = uilabel(app.IsolierungPanel);
            app.ObjekterkennungLamp_2Label.Text = 'Objekterkennung';
            app.ObjectDetection_Lamp = uilamp(app.IsolierungPanel);
            app.MesssystemPanel = uipanel(app.GesamtprozessTab);
            app.MesssystemPanel.Title = 'Messsystem';
            app.WeighingBelt_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.FrderbandLamp_4Label = uilabel(app.MesssystemPanel);
            app.FrderbandLamp_4Label.Text = 'Förderband';
            app.WeighingBelt_Lamp = uilamp(app.MesssystemPanel);
            app.Camera_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.Scale_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.KameraLampLabel = uilabel(app.MesssystemPanel);
            app.KameraLampLabel.Text = 'Kamera';
            app.Camera_Lamp = uilamp(app.MesssystemPanel);
            app.WaageLampLabel = uilabel(app.MesssystemPanel);
            app.WaageLampLabel.Text = 'Waage';
            app.Scale_Lamp = uilamp(app.MesssystemPanel);
            app.Light_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.LichtLabel = uilabel(app.MesssystemPanel);
            app.LichtLabel.Text = 'Licht';
            app.Light_Lamp = uilamp(app.MesssystemPanel);
            app.MeasuringSystem_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.MesssystemLabel = uilabel(app.GesamtprozessTab);
            app.MesssystemLabel.Text = 'Messsystem';
            app.MeasuringSystem_Lamp = uilamp(app.GesamtprozessTab);
            app.IsolationDevice_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.IsolierungLabel = uilabel(app.GesamtprozessTab);
            app.IsolierungLabel.Text = 'Isolierung';
            app.IsolationDevice_Lamp = uilamp(app.GesamtprozessTab);
            app.KommunikationPanel = uipanel(app.GesamtprozessTab);
            app.KommunikationPanel.Title = 'Kommunikation';
            app.Can_EditField = uieditfield(app.KommunikationPanel, 'text');
            app.CANBusLabel = uilabel(app.KommunikationPanel);
            app.CANBusLabel.Text = 'CAN-Bus';
            app.Can_Lamp = uilamp(app.KommunikationPanel);
            app.MesssystemTab = uitab(app.TabGroup);
            app.MesssystemTab.Title = 'Messsystem';
            app.KameraPanel = uipanel(app.MesssystemTab);
            app.KameraPanel.Title = 'Kamera';
            app.UVLampLabel = uilabel(app.MesssystemTab);
            app.UVLampLabel.Text = 'UV';
            app.UVLamp = uilamp(app.MesssystemTab);
            app.RotLampLabel = uilabel(app.MesssystemTab);
            app.RotLampLabel.Text = 'Rot';
            app.RotLamp = uilamp(app.MesssystemTab);
            app.GrnLampLabel = uilabel(app.MesssystemTab);
            app.GrnLampLabel.Text = 'Grün';
            app.GrnLamp = uilamp(app.MesssystemTab);
            app.BlauLampLabel = uilabel(app.MesssystemTab);
            app.BlauLampLabel.Text = 'Blau';
            app.BlauLamp = uilamp(app.MesssystemTab);
            app.WeiLampLabel = uilabel(app.MesssystemTab);
            app.WeiLampLabel.Text = 'Weiß';
            app.WeiLamp = uilamp(app.MesssystemTab);
            app.LichtBitcodeEditFieldLabel = uilabel(app.MesssystemTab);
            app.LichtBitcodeEditFieldLabel.Text = 'Licht-Bitcode';
            app.LichtBitcodeEditField = uieditfield(app.MesssystemTab, 'text');
            app.BindingfrEditfieldEditfieldChangedLampenLabel = uilabel(app.MesssystemTab);
            app.BindingfrEditfieldEditfieldChangedLampenLabel.Text = 'Binding für Editfield, Editfield.Changed -> Lampen';
            app.IsolationDevice_Tab = uitab(app.TabGroup);
            app.IsolationDevice_Tab.Title = 'Isolierung';
            app.IsolationDevice_TabGroup = uitabgroup(app.IsolationDevice_Tab);
            app.RoboterGreiferTab = uitab(app.IsolationDevice_TabGroup);
            app.RoboterGreiferTab.Title = 'Roboter/Greifer';
            app.ZustandEditFieldLabel = uilabel(app.RoboterGreiferTab);
            app.ZustandEditFieldLabel.Text = 'Zustand';
            app.ZustandEditField = uieditfield(app.RoboterGreiferTab, 'text');
            app.PosePanel = uipanel(app.RoboterGreiferTab);
            app.PosePanel.Title = 'Pose';
            app.xEditFieldLabel = uilabel(app.PosePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PosePanel, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.PosePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PosePanel, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.PosePanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PosePanel, 'numeric');
            app.zEditField.Enable = 'off';
            app.ryEditFieldLabel = uilabel(app.PosePanel);
            app.ryEditFieldLabel.Text = 'ry';
            app.ryEditField = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField.Enable = 'off';
            app.rzEditFieldLabel = uilabel(app.PosePanel);
            app.rzEditFieldLabel.Text = 'rz';
            app.rzEditField = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField.Enable = 'off';
            app.rxEditFieldLabel = uilabel(app.PosePanel);
            app.rxEditFieldLabel.Text = 'rx';
            app.rxEditField = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField.Enable = 'off';
            app.SteuerungCheckBox = uicheckbox(app.PosePanel);
            app.SteuerungCheckBox.Text = 'Steuerung';
            app.BewegenButton = uibutton(app.PosePanel, 'push');
            app.BewegenButton.Text = 'Bewegen';
            app.SollLabel = uilabel(app.PosePanel);
            app.SollLabel.Text = 'Soll';
            app.IstLabel = uilabel(app.PosePanel);
            app.IstLabel.Text = 'Ist';
            app.xEditField_2Label = uilabel(app.PosePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.xEditField_2.Editable = 'off';
            app.yEditField_2Label = uilabel(app.PosePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.yEditField_2.Editable = 'off';
            app.zEditField_2Label = uilabel(app.PosePanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.zEditField_2.Editable = 'off';
            app.ryEditField_2Label = uilabel(app.PosePanel);
            app.ryEditField_2Label.Text = 'ry';
            app.ryEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField_2.Editable = 'off';
            app.rzEditField_2Label = uilabel(app.PosePanel);
            app.rzEditField_2Label.Text = 'rz';
            app.rzEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField_2.Editable = 'off';
            app.rxEditField_2Label = uilabel(app.PosePanel);
            app.rxEditField_2Label.Text = 'rx';
            app.rxEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField_2.Editable = 'off';
            app.GreiferPanel = uipanel(app.RoboterGreiferTab);
            app.GreiferPanel.Title = 'Greifer';
            app.ZangeLinksGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeLinksGaugeLabel.Text = 'Zange Links';
            app.ZangeLinksGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeLinksGauge.Limits = [0 120];
            app.ZangeLinksGauge.MajorTicks = [0 30 60 90 120];
            app.ZangeLinksGauge.MajorTickLabels = {'0', '', '60', '', '120'};
            app.ZangeLinksGauge.ScaleDirection = 'counterclockwise';
            app.ZangeRechtsGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeRechtsGaugeLabel.Text = 'Zange Rechts';
            app.ZangeRechtsGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeRechtsGauge.Limits = [0 120];
            app.ZangeRechtsGauge.MajorTicks = [0 30 60 90 120];
            app.ZangeRechtsGauge.MajorTickLabels = {'0', '', '60', '', '120'};
            app.KontaktLampLabel = uilabel(app.GreiferPanel);
            app.KontaktLampLabel.Text = 'Kontakt';
            app.KontaktLamp = uilamp(app.GreiferPanel);
            app.OffsetEditFieldLabel = uilabel(app.GreiferPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GreiferPanel, 'numeric');
            app.ZangeRechts_EditField = uieditfield(app.GreiferPanel, 'numeric');
            app.ZangeLinks_EditField = uieditfield(app.GreiferPanel, 'numeric');
            app.OpenCloseGripper_Button = uibutton(app.GreiferPanel, 'state');
            app.OpenCloseGripper_Button.Text = 'Öffnen/Schließen';
            app.UIAxes = uiaxes(app.RoboterGreiferTab);
            title(app.UIAxes, 'Roboter Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UnterdruckpumpePanel = uipanel(app.RoboterGreiferTab);
            app.UnterdruckpumpePanel.Title = 'Unterdruckpumpe';
            app.SignalLampLabel = uilabel(app.UnterdruckpumpePanel);
            app.SignalLampLabel.Text = 'Signal';
            app.VacuumSignalLamp = uilamp(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel = uilabel(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel.Text = 'Druck';
            app.DruckGauge = uigauge(app.UnterdruckpumpePanel, 'linear');
            app.AnAusButton = uibutton(app.UnterdruckpumpePanel, 'push');
            app.AnAusButton.Text = 'An/Aus';
            app.FrderbandPanel = uipanel(app.RoboterGreiferTab);
            app.FrderbandPanel.Title = 'Förderband';
            app.LichtschrankeLampLabel = uilabel(app.FrderbandPanel);
            app.LichtschrankeLampLabel.Text = 'Lichtschranke';
            app.IsoLichtschrankeLamp = uilamp(app.FrderbandPanel);
            app.SliderLabel = uilabel(app.FrderbandPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.FrderbandPanel);
            app.AnAusButton_2 = uibutton(app.FrderbandPanel, 'state');
            app.AnAusButton_2.Text = 'An/Aus';
            app.ObjekterkennungTab = uitab(app.IsolationDevice_TabGroup);
            app.ObjekterkennungTab.Title = 'Objekterkennung';
            app.Vorschau_Button = uibutton(app.ObjekterkennungTab, 'state');
            app.Vorschau_Button.ValueChangedFcn = createCallbackFcn(app, @Vorschau_ButtonValueChanged, true);
            app.Vorschau_Button.Text = 'Vorschau starten';
            app.BereichObjekterkennungPanel = uipanel(app.ObjekterkennungTab);
            app.BereichObjekterkennungPanel.Title = 'Bereich: Objekterkennung   (rot)';
            app.XSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.XSpinnerLabel.Text = 'X';
            app.ROI_X_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_X_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_X_SpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.ROI_Y_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Y_SpinnerValueChanged, true);
            app.HheSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.HheSpinnerLabel.Text = 'Höhe';
            app.ROI_Hohe_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Hohe_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Hohe_SpinnerValueChanged, true);
            app.BreiteSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.BreiteSpinnerLabel.Text = 'Breite';
            app.ROI_Breite_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Breite_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Breite_SpinnerValueChanged, true);
            app.ROIShow_Button = uibutton(app.BereichObjekterkennungPanel, 'state');
            app.ROIShow_Button.ValueChangedFcn = createCallbackFcn(app, @ROIShow_ButtonValueChanged, true);
            app.ROIShow_Button.Text = 'Bereich anzeigen';
            app.ObjDetectCam_Axes = uiaxes(app.ObjekterkennungTab);
            title(app.ObjDetectCam_Axes, 'Title')
            xlabel(app.ObjDetectCam_Axes, 'X')
            ylabel(app.ObjDetectCam_Axes, 'Y')
            app.BereichGreifenPanel = uipanel(app.ObjekterkennungTab);
            app.BereichGreifenPanel.Title = 'Roboter<Bereich: Greifen (grün)';
            app.XSpinnerLabel_2 = uilabel(app.BereichGreifenPanel);
            app.XSpinnerLabel_2.Text = 'X';
            app.ROA_X_Spinner = uispinner(app.BereichGreifenPanel);
            app.ROA_X_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROA_X_SpinnerValueChanged, true);
            app.YSpinnerLabel_2 = uilabel(app.BereichGreifenPanel);
            app.YSpinnerLabel_2.Text = 'Y';
            app.ROA_Y_Spinner = uispinner(app.BereichGreifenPanel);
            app.ROA_Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROA_Y_SpinnerValueChanged, true);
            app.HheSpinnerLabel_2 = uilabel(app.BereichGreifenPanel);
            app.HheSpinnerLabel_2.Text = 'Höhe';
            app.ROA_Hohe_Spinner = uispinner(app.BereichGreifenPanel);
            app.ROA_Hohe_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROA_Hohe_SpinnerValueChanged, true);
            app.BreiteSpinnerLabel_2 = uilabel(app.BereichGreifenPanel);
            app.BreiteSpinnerLabel_2.Text = 'Breite';
            app.ROA_Breite_Spinner = uispinner(app.BereichGreifenPanel);
            app.ROA_Breite_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROA_Breite_SpinnerValueChanged, true);
            app.ROAShow_Button = uibutton(app.BereichGreifenPanel, 'state');
            app.ROAShow_Button.ValueChangedFcn = createCallbackFcn(app, @ROAShow_ButtonValueChanged, true);
            app.ROAShow_Button.Text = 'Bereich anzeigen';
            app.FrderbandTab = uitab(app.IsolationDevice_TabGroup);
            app.FrderbandTab.Title = 'Förderband';
            app.TrommelTab = uitab(app.IsolationDevice_TabGroup);
            app.TrommelTab.Title = 'Trommel';
            app.LoggingPanel = uipanel(app.PMVAltpapierKlassifiziererUIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.FehlerCheckBox = uicheckbox(app.LoggingPanel);
            app.FehlerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FehlerCheckBoxValueChanged, true);
            app.FehlerCheckBox.Text = 'Fehler';
            app.FehlerCheckBox.Value = true;
            app.WarnungCheckBox = uicheckbox(app.LoggingPanel);
            app.WarnungCheckBox.ValueChangedFcn = createCallbackFcn(app, @WarnungCheckBoxValueChanged, true);
            app.WarnungCheckBox.Text = 'Warnung';
            app.WarnungCheckBox.Value = true;
            app.InfoCheckBox = uicheckbox(app.LoggingPanel);
            app.InfoCheckBox.ValueChangedFcn = createCallbackFcn(app, @InfoCheckBoxValueChanged, true);
            app.InfoCheckBox.Text = 'Info';
            app.InfoCheckBox.Value = true;
            app.DebugCheckBox = uicheckbox(app.LoggingPanel);
            app.DebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugCheckBoxValueChanged, true);
            app.DebugCheckBox.Text = 'Debug';
            app.DebugCheckBox.Value = true;
            app.Log_UITable = uitable(app.LoggingPanel);
            app.Log_UITable.ColumnName = {'Zeit'; 'Level'; 'Ursprung'; 'Nachricht'};
            app.Log_UITable.RowName = {};
            app.angezeigteLogsSpinnerLabel = uilabel(app.LoggingPanel);
            app.angezeigteLogsSpinnerLabel.Text = '# angezeigte Logs ';
            app.AngezeigteLogs_Spinner = uispinner(app.LoggingPanel);
            app.AngezeigteLogs_Spinner.Step = 10;
            app.AngezeigteLogs_Spinner.Limits = [1 Inf];
            app.AngezeigteLogs_Spinner.RoundFractionalValues = 'on';
            app.AngezeigteLogs_Spinner.ValueChangedFcn = createCallbackFcn(app, @AngezeigteLogs_SpinnerValueChanged, true);
            app.AngezeigteLogs_Spinner.Value = 100;
            app.FilterEditFieldLabel = uilabel(app.LoggingPanel);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.LogFilter_EditField = uieditfield(app.LoggingPanel, 'text');
            app.LogFilter_EditField.ValueChangedFcn = createCallbackFcn(app, @LogFilter_EditFieldValueChanged, true);
            app.SteuerungPanel = uipanel(app.PMVAltpapierKlassifiziererUIFigure);
            app.SteuerungPanel.Title = 'Steuerung';
            app.Start_Button = uibutton(app.SteuerungPanel, 'push');
            app.Start_Button.ButtonPushedFcn = createCallbackFcn(app, @Start_ButtonPushed, true);
            app.Start_Button.Text = 'Init';
            app.Stop_Button = uibutton(app.SteuerungPanel, 'push');
            app.Stop_Button.ButtonPushedFcn = createCallbackFcn(app, @Stop_ButtonPushed, true);
            app.Stop_Button.Enable = 'off';
            app.Stop_Button.Text = 'Stop';
            app.ProbenLabel = uilabel(app.SteuerungPanel);
            app.ProbenLabel.Enable = 'off';
            app.ProbenLabel.Text = 'Proben';
            app.Samples_Spinner = uispinner(app.SteuerungPanel);
            app.Samples_Spinner.Limits = [0 Inf];
            app.Samples_Spinner.RoundFractionalValues = 'on';
            app.Samples_Spinner.ValueDisplayFormat = '%.0f';
            app.Samples_Spinner.Enable = 'off';
            app.ContinuousOperation_CheckBox = uicheckbox(app.SteuerungPanel);
            app.ContinuousOperation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousOperation_CheckBoxValueChanged, true);
            app.ContinuousOperation_CheckBox.Text = 'Dauerlauf';
            app.ContinuousOperation_CheckBox.Value = true;
            app.Process_EditField = uieditfield(app.SteuerungPanel, 'text');
            app.ProzessLabel = uilabel(app.SteuerungPanel);
            app.ProzessLabel.Text = 'Prozess';
            app.Process_Lamp = uilamp(app.SteuerungPanel);
            app.Stop_Button_2 = uibutton(app.SteuerungPanel, 'push');
            app.Stop_Button_2.Enable = 'off';
            app.Stop_Button_2.Text = 'Entstören';
            app.Image = uiimage(app.SteuerungPanel);
            app.Image.ImageSource = 'pmv_logo.png';
            app.RemainingSamplesSlash_Label = uilabel(app.SteuerungPanel);
            app.RemainingSamplesSlash_Label.Text = '/';
            app.RemainingSamples_EditField = uieditfield(app.SteuerungPanel, 'numeric');
            app.RemainingSamples_EditField.Editable = 'off';
            app.RemainingSamples_EditField.Enable = 'off';
            % Show the figure after all components are created
            app.PMVAltpapierKlassifiziererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PMVAltpapierKlassifiziererUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PMVAltpapierKlassifiziererUIFigure)
        end
    end
end