classdef SettingsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EinstellungenUIFigure     matlab.ui.Figure
        GUIPanel                  matlab.ui.container.Panel
        FrequenzZustandsabfrageEditFieldLabel  matlab.ui.control.Label
        FrequenzZustandsabfrageEditField  matlab.ui.control.NumericEditField
        AbbrechenButton           matlab.ui.control.Button
        OkButton                  matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        LoggerPanel               matlab.ui.container.Panel
        LogKonsole_CheckBox       matlab.ui.control.CheckBox
        LogDatei_Button           matlab.ui.control.Button
        LogLevelDropDownLabel     matlab.ui.control.Label
        LogLevelKonsole_DropDown  matlab.ui.control.DropDown
        LogDatei_CheckBox         matlab.ui.control.CheckBox
        LogLevelDropDown_2Label   matlab.ui.control.Label
        LogLevelDatei_DropDown    matlab.ui.control.DropDown
        LogGUI_CheckBox           matlab.ui.control.CheckBox
        LogLevelDropDown_3Label   matlab.ui.control.Label
        LogLevelGUI_DropDown      matlab.ui.control.DropDown
        LogDatei_EditField        matlab.ui.control.EditField
        LogFormatEditFieldLabel   matlab.ui.control.Label
        LogFormat_EditField       matlab.ui.control.EditField
        ZeitFormatEditFieldLabel  matlab.ui.control.Label
        ZeitFormat_EditField      matlab.ui.control.EditField
        LogStoreDelete_Button     matlab.ui.control.Button
        LogStoreSave_Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        process_gui % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, process_gui)
            if isempty(process_gui.logger)
                for i = 1:length(app.LoggerPanel.Children)
                    app.LoggerPanel.Children(i).Enable = false;
                end
            else
                app.LogFormat_EditField.Value = process_gui.logger.log_format;
                app.LogFormat_EditField.Tooltip = {...
                    '%time - Zeit',...
                    '%level - Level',...
                    '%source - Ursprung',...
                    '%message - Nachricht'};
                app.ZeitFormat_EditField.Value = process_gui.logger.time_format;
                app.ZeitFormat_EditField.Tooltip = {...
                    'yyyy - Year in full',...
                    'yy - Year in two digits',...
                    'QQ - Quarter year using letter Q and one digit',...
                    'mmmm - Month using full name',...
                    'mmm - Month using first three letters',...
                    'mm - Month in two digits',...
                    'm - Month using capitalized first letter',...
                    'dddd - Day using full name',...
                    'ddd - Day using first three letters',...
                    'dd - Day in two digits',...
                    'd - Day using capitalized first letter',...
                    'HH - Hour in two digits  - (no leading zeros when symbolic identifier AM or PM is used)',...
                    'MM - Minute in two digits','SS - Second in two digits',...
                    'FFF - Millisecond in three digits',...
                    'AM or PM - AM or PM inserted in text representing time'};
                
                app.LogKonsole_CheckBox.Value = process_gui.logger.log_print_active;
                app.LogLevelKonsole_DropDown.Items = process_gui.logger.level_names;
                app.LogLevelKonsole_DropDown.Value = process_gui.logger.level_names(process_gui.logger.log_print_level);
                
                app.LogDatei_CheckBox.Value = process_gui.logger.log_file_active;
                app.LogLevelDatei_DropDown.Items = process_gui.logger.level_names;
                app.LogLevelDatei_DropDown.Value = process_gui.logger.level_names(process_gui.logger.log_file_level);
                app.LogDatei_EditField.Value = process_gui.logger.log_file_path;
                
                app.LogGUI_CheckBox.Value = process_gui.logger.log_remote_fcn_active;
                app.LogLevelGUI_DropDown.Items = process_gui.logger.level_names;
                app.LogLevelGUI_DropDown.Value = process_gui.logger.level_names(process_gui.logger.log_remote_fcn_level);
                
                app.process_gui = process_gui;
            end
        end

        % Button pushed function: LogDatei_Button
        function LogDatei_ButtonPushed(app, event)
            [file,path] = uiputfile('.txt','Log-Datei auswählen');
            if file ~= 0
                app.LogDatei_EditField.Value = [path,file];
            end
        end

        % Button pushed function: LogStoreSave_Button
        function LogStoreSave_ButtonPushed(app, event)
            for i = 1:length(app.process_gui.logger.log_store)
                app.process_gui.logger.logToFile(app.process_gui.logger.log_store(i));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EinstellungenUIFigure = uifigure;
            app.EinstellungenUIFigure.Name = 'Einstellungen';
            app.GUIPanel = uipanel(app.EinstellungenUIFigure);
            app.GUIPanel.Title = 'GUI';
            app.FrequenzZustandsabfrageEditFieldLabel = uilabel(app.GUIPanel);
            app.FrequenzZustandsabfrageEditFieldLabel.Text = 'Frequenz - Zustandsabfrage';
            app.FrequenzZustandsabfrageEditField = uieditfield(app.GUIPanel, 'numeric');
            app.AbbrechenButton = uibutton(app.EinstellungenUIFigure, 'push');
            app.AbbrechenButton.Text = 'Abbrechen';
            app.OkButton = uibutton(app.EinstellungenUIFigure, 'push');
            app.OkButton.Text = 'Ok';
            app.ApplyButton = uibutton(app.EinstellungenUIFigure, 'push');
            app.ApplyButton.Text = 'Apply';
            app.LoggerPanel = uipanel(app.EinstellungenUIFigure);
            app.LoggerPanel.Title = 'Logger';
            app.LogKonsole_CheckBox = uicheckbox(app.LoggerPanel);
            app.LogKonsole_CheckBox.Text = 'Log -> Konsole';
            app.LogDatei_Button = uibutton(app.LoggerPanel, 'push');
            app.LogDatei_Button.ButtonPushedFcn = createCallbackFcn(app, @LogDatei_ButtonPushed, true);
            app.LogDatei_Button.Text = 'Log-Datei wählen...';
            app.LogLevelDropDownLabel = uilabel(app.LoggerPanel);
            app.LogLevelDropDownLabel.Text = 'Log Level';
            app.LogLevelKonsole_DropDown = uidropdown(app.LoggerPanel);
            app.LogLevelKonsole_DropDown.Tooltip = {'Niedrigstes Level das im Log erscheint'};
            app.LogDatei_CheckBox = uicheckbox(app.LoggerPanel);
            app.LogDatei_CheckBox.Text = 'Log -> Datei';
            app.LogLevelDropDown_2Label = uilabel(app.LoggerPanel);
            app.LogLevelDropDown_2Label.Text = 'Log Level';
            app.LogLevelDatei_DropDown = uidropdown(app.LoggerPanel);
            app.LogLevelDatei_DropDown.Tooltip = {'Niedrigstes Level das im Log erscheint'};
            app.LogGUI_CheckBox = uicheckbox(app.LoggerPanel);
            app.LogGUI_CheckBox.Text = 'Log -> GUI';
            app.LogLevelDropDown_3Label = uilabel(app.LoggerPanel);
            app.LogLevelDropDown_3Label.Enable = 'off';
            app.LogLevelDropDown_3Label.Text = 'Log Level';
            app.LogLevelGUI_DropDown = uidropdown(app.LoggerPanel);
            app.LogLevelGUI_DropDown.Enable = 'off';
            app.LogLevelGUI_DropDown.Tooltip = {'Niedrigstes Level das im Log erscheint (Durch System gesteuert)'};
            app.LogDatei_EditField = uieditfield(app.LoggerPanel, 'text');
            app.LogDatei_EditField.Editable = 'off';
            app.LogFormatEditFieldLabel = uilabel(app.LoggerPanel);
            app.LogFormatEditFieldLabel.Text = 'Log Format';
            app.LogFormat_EditField = uieditfield(app.LoggerPanel, 'text');
            app.ZeitFormatEditFieldLabel = uilabel(app.LoggerPanel);
            app.ZeitFormatEditFieldLabel.Text = 'Zeit Format';
            app.ZeitFormat_EditField = uieditfield(app.LoggerPanel, 'text');
            app.LogStoreDelete_Button = uibutton(app.LoggerPanel, 'push');
            app.LogStoreDelete_Button.Text = 'Bestehende Logs löschen';
            app.LogStoreSave_Button = uibutton(app.LoggerPanel, 'push');
            app.LogStoreSave_Button.ButtonPushedFcn = createCallbackFcn(app, @LogStoreSave_ButtonPushed, true);
            app.LogStoreSave_Button.Text = 'Bestehende Logs speichern';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SettingsGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EinstellungenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EinstellungenUIFigure)
        end
    end
end